classdef trainingDataGeneratorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        img14                  matlab.ui.control.UIAxes
        img15                  matlab.ui.control.UIAxes
        img16                  matlab.ui.control.UIAxes
        img17                  matlab.ui.control.UIAxes
        img18                  matlab.ui.control.UIAxes
        lb14                   matlab.ui.control.Label
        lb15                   matlab.ui.control.Label
        lb16                   matlab.ui.control.Label
        lb17                   matlab.ui.control.Label
        lb18                   matlab.ui.control.Label
        ClassesButtonGroup_14  matlab.ui.container.ButtonGroup
        BGButton14             matlab.ui.control.ToggleButton
        MXButton14             matlab.ui.control.ToggleButton
        RHButton14             matlab.ui.control.ToggleButton
        INButton14             matlab.ui.control.ToggleButton
        ClassesButtonGroup_15  matlab.ui.container.ButtonGroup
        Button                 matlab.ui.control.ToggleButton
        Button_2               matlab.ui.control.ToggleButton
        Button_3               matlab.ui.control.ToggleButton
        Button_4               matlab.ui.control.ToggleButton
        ClassesButtonGroup_16  matlab.ui.container.ButtonGroup
        Button_5               matlab.ui.control.ToggleButton
        Button_6               matlab.ui.control.ToggleButton
        Button_7               matlab.ui.control.ToggleButton
        Button_8               matlab.ui.control.ToggleButton
        ClassesButtonGroup_17  matlab.ui.container.ButtonGroup
        Button_9               matlab.ui.control.ToggleButton
        Button_10              matlab.ui.control.ToggleButton
        Button_11              matlab.ui.control.ToggleButton
        Button_12              matlab.ui.control.ToggleButton
        ClassesButtonGroup_18  matlab.ui.container.ButtonGroup
        Button_13              matlab.ui.control.ToggleButton
        Button_14              matlab.ui.control.ToggleButton
        Button_15              matlab.ui.control.ToggleButton
        Button_16              matlab.ui.control.ToggleButton
        currlb_14Label         matlab.ui.control.Label
        currlb_15Label         matlab.ui.control.Label
        currlb_16Label         matlab.ui.control.Label
        currlb_17Label         matlab.ui.control.Label
        currlb_18Label         matlab.ui.control.Label
        ControlcalssesPanel    matlab.ui.container.Panel
        NextButton             matlab.ui.control.Button
        ApplyButton            matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function updateimage(app, imgs)
            try
              i14=imread(string(imgs(1)));
              i15=imread(string(imgs(4)));
              i16=imread(string(imgs(7)));
              i17=imread(string(imgs(10)));
              i18=imread(string(imgs(13)));
                
              imshow(i14,'Parent',app.img14);
              imshow(i15,'Parent',app.img15);
              imshow(i16,'Parent',app.img16);
              imshow(i17,'Parent',app.img17);
              imshow(i18,'Parent',app.img18);

              app.currlb_14Label.Text = string(cell2mat(imgs(:,3)));
              app.currlb_15Label.Text = string(cell2mat(imgs(:,6)));
              app.currlb_16Label.Text = string(cell2mat(imgs(:,9)));
              app.currlb_17Label.Text = string(cell2mat(imgs(:,12)));
              app.currlb_18Label.Text = string(cell2mat(imgs(:,15)));
                
              app.Label.Text = string(cell2mat(imgs(:,2)));

            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end                
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global m;
            m = 3500;
            global d;
            load('G:\backupC27152020\C\models2\matlab\trainTraj_4643', 'd');
            imgs = d(m,:);
            
            trajchange(1) = cell2mat(imgs(6))-cell2mat(imgs(:,3));
            trajchange(2) = cell2mat(imgs(9))-cell2mat(imgs(6));
            trajchange(3) = cell2mat(imgs(12))-cell2mat(imgs(9));
            trajchange(4) = cell2mat(imgs(15))-cell2mat(imgs(12));
            trajchange(trajchange~=0)=1;
            
            if sum(trajchange)>1
                % Update the image 14
                updateimage(app, imgs);  
            else
                updateimage(app, imgs); 
                app.Label.Text = string(cell2mat(imgs(:,2))) + ' is correct!';
            end
              
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            global m;
            global d;
            d(m,3) = mat2cell(str2num(app.ClassesButtonGroup_14.SelectedObject.Text), 1);
            d(m,6) = mat2cell(str2num(app.ClassesButtonGroup_15.SelectedObject.Text), 1);
            d(m,9) = mat2cell(str2num(app.ClassesButtonGroup_16.SelectedObject.Text), 1);
            d(m,12) = mat2cell(str2num(app.ClassesButtonGroup_17.SelectedObject.Text), 1);
            d(m,15) = mat2cell(str2num(app.ClassesButtonGroup_18.SelectedObject.Text), 1);
            d(m,:)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global m;
            global d;
            m = m + 1;
            ig = d(m,:);
            
            trajchange(1) = cell2mat(ig(6))-cell2mat(ig(:,3));
            trajchange(2) = cell2mat(ig(9))-cell2mat(ig(6));
            trajchange(3) = cell2mat(ig(12))-cell2mat(ig(9));
            trajchange(4) = cell2mat(ig(15))-cell2mat(ig(12));
            trajchange(trajchange~=0)=1
            
            if sum(trajchange)>1
                % Update the image 14
                updateimage(app, ig);  
            else
               updateimage(app, ig);
               app.Label.Text = string(cell2mat(ig(:,2))) + ' is correct!';
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global m;
            global d;
            name = 'trainTraj_' + string(m);
            save(name, 'd');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.img14 = uiaxes(app.UIFigure);
            app.img14.XTick = [];
            app.img14.XTickLabel = {'[ ]'};
            app.img14.YTick = [];
            app.img15 = uiaxes(app.UIFigure);
            app.img15.XTick = [];
            app.img15.XTickLabel = {'[ ]'};
            app.img15.YTick = [];
            app.img16 = uiaxes(app.UIFigure);
            app.img16.XTick = [];
            app.img16.XTickLabel = {'[ ]'};
            app.img16.YTick = [];
            app.img17 = uiaxes(app.UIFigure);
            app.img17.XTick = [];
            app.img17.XTickLabel = {'[ ]'};
            app.img17.YTick = [];
            app.img18 = uiaxes(app.UIFigure);
            app.img18.XTick = [];
            app.img18.XTickLabel = {'[ ]'};
            app.img18.YTick = [];
            app.lb14 = uilabel(app.UIFigure);
            app.lb14.Text = '2014';
            app.lb15 = uilabel(app.UIFigure);
            app.lb15.Text = '2015';
            app.lb16 = uilabel(app.UIFigure);
            app.lb16.Text = '2016';
            app.lb17 = uilabel(app.UIFigure);
            app.lb17.Text = '2017';
            app.lb18 = uilabel(app.UIFigure);
            app.lb18.Text = '2018';
            app.ClassesButtonGroup_14 = uibuttongroup(app.UIFigure);
            app.ClassesButtonGroup_14.Title = 'Classes';
            app.BGButton14 = uitogglebutton(app.ClassesButtonGroup_14);
            app.BGButton14.Text = '1';
            app.BGButton14.Value = true;
            app.MXButton14 = uitogglebutton(app.ClassesButtonGroup_14);
            app.MXButton14.Text = '2';
            app.RHButton14 = uitogglebutton(app.ClassesButtonGroup_14);
            app.RHButton14.Text = '3';
            app.INButton14 = uitogglebutton(app.ClassesButtonGroup_14);
            app.INButton14.Text = '4';
            app.ClassesButtonGroup_15 = uibuttongroup(app.UIFigure);
            app.ClassesButtonGroup_15.Title = 'Classes';
            app.Button = uitogglebutton(app.ClassesButtonGroup_15);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ClassesButtonGroup_15);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.ClassesButtonGroup_15);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.ClassesButtonGroup_15);
            app.Button_4.Text = '4';
            app.ClassesButtonGroup_16 = uibuttongroup(app.UIFigure);
            app.ClassesButtonGroup_16.Title = 'Classes';
            app.Button_5 = uitogglebutton(app.ClassesButtonGroup_16);
            app.Button_5.Text = '1';
            app.Button_5.Value = true;
            app.Button_6 = uitogglebutton(app.ClassesButtonGroup_16);
            app.Button_6.Text = '2';
            app.Button_7 = uitogglebutton(app.ClassesButtonGroup_16);
            app.Button_7.Text = '3';
            app.Button_8 = uitogglebutton(app.ClassesButtonGroup_16);
            app.Button_8.Text = '4';
            app.ClassesButtonGroup_17 = uibuttongroup(app.UIFigure);
            app.ClassesButtonGroup_17.Title = 'Classes';
            app.Button_9 = uitogglebutton(app.ClassesButtonGroup_17);
            app.Button_9.Text = '1';
            app.Button_9.Value = true;
            app.Button_10 = uitogglebutton(app.ClassesButtonGroup_17);
            app.Button_10.Text = '2';
            app.Button_11 = uitogglebutton(app.ClassesButtonGroup_17);
            app.Button_11.Text = '3';
            app.Button_12 = uitogglebutton(app.ClassesButtonGroup_17);
            app.Button_12.Text = '4';
            app.ClassesButtonGroup_18 = uibuttongroup(app.UIFigure);
            app.ClassesButtonGroup_18.Title = 'Classes';
            app.Button_13 = uitogglebutton(app.ClassesButtonGroup_18);
            app.Button_13.Text = '1';
            app.Button_13.Value = true;
            app.Button_14 = uitogglebutton(app.ClassesButtonGroup_18);
            app.Button_14.Text = '2';
            app.Button_15 = uitogglebutton(app.ClassesButtonGroup_18);
            app.Button_15.Text = '3';
            app.Button_16 = uitogglebutton(app.ClassesButtonGroup_18);
            app.Button_16.Text = '4';
            app.currlb_14Label = uilabel(app.UIFigure);
            app.currlb_14Label.Text = 'currlb_14';
            app.currlb_15Label = uilabel(app.UIFigure);
            app.currlb_15Label.Text = 'currlb_15';
            app.currlb_16Label = uilabel(app.UIFigure);
            app.currlb_16Label.Text = 'currlb_16';
            app.currlb_17Label = uilabel(app.UIFigure);
            app.currlb_17Label.Text = 'currlb_17';
            app.currlb_18Label = uilabel(app.UIFigure);
            app.currlb_18Label.Text = 'currlb_18';
            app.ControlcalssesPanel = uipanel(app.UIFigure);
            app.ControlcalssesPanel.Title = 'Control calsses';
            app.NextButton = uibutton(app.ControlcalssesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ApplyButton = uibutton(app.ControlcalssesPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveButton = uibutton(app.ControlcalssesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.ControlcalssesPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trainingDataGeneratorGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end